classdef cam_imaging_live < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ReadoutRateDropDown         matlab.ui.control.DropDown
        ReadoutRateDropDownLabel    matlab.ui.control.Label
        GainDropDown                matlab.ui.control.DropDown
        GainDropDownLabel           matlab.ui.control.Label
        PModeDropDown               matlab.ui.control.DropDown
        PModeDropDownLabel          matlab.ui.control.Label
        SaveonlydifferenceCheckBox  matlab.ui.control.CheckBox
        SavedifferenceCheckBox      matlab.ui.control.CheckBox
        MeanIntEditField_2          matlab.ui.control.NumericEditField
        MeanIntEditField_2Label     matlab.ui.control.Label
        MeanIntEditField            matlab.ui.control.NumericEditField
        MeanIntEditFieldLabel       matlab.ui.control.Label
        MedianfilterSpinner         matlab.ui.control.Spinner
        MedianfilterSpinnerLabel    matlab.ui.control.Label
        BinningDropDown             matlab.ui.control.DropDown
        BinningDropDownLabel        matlab.ui.control.Label
        AutoCheckBox_2              matlab.ui.control.CheckBox
        AutoCheckBox                matlab.ui.control.CheckBox
        AutoClimButton_2            matlab.ui.control.Button
        AutoClimButton              matlab.ui.control.Button
        SaveButton_2                matlab.ui.control.Button
        SelectFolderButton_2        matlab.ui.control.Button
        SnapButton                  matlab.ui.control.Button
        ColormapDropDown_2          matlab.ui.control.DropDown
        ColormapDropDown_2Label     matlab.ui.control.Label
        ColormapDropDown            matlab.ui.control.DropDown
        ColormapDropDownLabel       matlab.ui.control.Label
        CurrentimageEditField       matlab.ui.control.NumericEditField
        CurrentimageEditFieldLabel  matlab.ui.control.Label
        NofimgstostackSpinner       matlab.ui.control.Spinner
        NofimgstostackSpinnerLabel  matlab.ui.control.Label
        AppSettingPanel             matlab.ui.container.Panel
        ErrorsLamp                  matlab.ui.control.Lamp
        ErrorsLampLabel             matlab.ui.control.Label
        ErrorlaberLabel             matlab.ui.control.Label
        PausebetweenframesmsIncreaseitifappdoesnotresponseSpinner  matlab.ui.control.Spinner
        PausebetweenframesmsIncreaseitifappdoesnotresponseLabel  matlab.ui.control.Label
        CminEditField_2             matlab.ui.control.NumericEditField
        CminEditField_2Label        matlab.ui.control.Label
        CmaxEditField_2             matlab.ui.control.NumericEditField
        CmaxEditField_2Label        matlab.ui.control.Label
        filenameEditField           matlab.ui.control.EditField
        filenameEditFieldLabel      matlab.ui.control.Label
        pathEditField               matlab.ui.control.EditField
        pathEditFieldLabel          matlab.ui.control.Label
        CmaxEditField               matlab.ui.control.NumericEditField
        CmaxEditFieldLabel          matlab.ui.control.Label
        CminEditField               matlab.ui.control.NumericEditField
        CminEditFieldLabel          matlab.ui.control.Label
        ExposuretimeSpinner         matlab.ui.control.Spinner
        ExposuretimeSpinnerLabel    matlab.ui.control.Label
        MakereferenceButton         matlab.ui.control.Button
        StopliveButton              matlab.ui.control.Button
        StartliveButton             matlab.ui.control.Button
        AxisHysto_diff              matlab.ui.control.UIAxes
        AxisHysto_real              matlab.ui.control.UIAxes
        Axes_realImage              matlab.ui.control.UIAxes
        Axes_difference             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        cam %camera
        mmc %MicroManager core
        realImage = 0; %one shot image
        sumImage = 0;
        referenceImage = 0; %to store background image
        differenceImage =0; %to store difference
        isStop;
    end

    methods (Access = private)
        
        function img = snapImage(app) %snap one image using micromanager
            %________ protocol from https://micro-manager.org/Matlab_Configuration
            app.ErrorlaberLabel.Text = 'taking image...';
            app.ErrorsLamp.Color = 'r';
            app.mmc.snapImage();
            img = app.mmc.getImage(); % returned as a 1D array of signed integers in row-major order
            width = app.mmc.getImageWidth(); 
            height = app.mmc.getImageHeight; 

            if app.mmc.getBytesPerPixel == 2
                pixelType = 'uint16';
            else
                pixelType = 'uint8';
            end
            img = typecast(img, pixelType);
            img = reshape(img, [width, height]); % image should be interpreted as a 2D array
            img = transpose(img);                % make column-major order for MATLAB
            app.ErrorsLamp.Color = 'g';
            app.ErrorlaberLabel.Text = 'image ready';            
        end

        function update_realImage(app) % update real image axes
            imagesc(app.Axes_realImage, app.realImage);
            app.Axes_realImage.YLim = [-inf inf];
            app.Axes_realImage.XLim = [-inf inf];
            app.Axes_realImage.PlotBoxAspectRatio = [size(app.realImage,2), size(app.realImage,1), 1];
            colorbar(app.Axes_realImage);
            if app.AutoCheckBox.Value
                autoAxis(app, app.Axes_realImage);
            end
            dcm = datacursormode(ancestor(app.Axes_realImage, 'figure'));
            dcm.Enable = 'on';
            histogram(app.AxisHysto_real, app.realImage);
            app.MeanIntEditField.Value = mean(mean(app.realImage));
        end

        function update_difference(app) % update difference image axes
            app.differenceImage = int16(app.realImage) - int16(app.referenceImage);
            imagesc(app.Axes_difference, app.differenceImage);
            app.Axes_difference.YLim = [-inf inf];
            app.Axes_difference.XLim = [-inf inf];
            app.Axes_difference.PlotBoxAspectRatio = [size(app.differenceImage,2), size(app.differenceImage,1), 1];
            colorbar(app.Axes_difference);
            dcm = datacursormode(ancestor(app.Axes_difference, 'figure'));
            dcm.Enable = 'on';
            histogram(app.AxisHysto_diff, app.differenceImage);
            app.MeanIntEditField_2.Value = mean(mean(app.differenceImage));
        end

        function takeSnap(app) % snap multi image           
            app.isStop = false;
            app.realImage = 0;
%             value = app.BinningDropDown.Value;
            for ii = 1:app.NofimgstostackSpinner.Value
                % Capture a frame from the cam                
                if ~app.isStop 
                    pause(app.PausebetweenframesmsIncreaseitifappdoesnotresponseSpinner.Value / 1000);
                    img = app.snapImage();
                    % use median filter
                    value = app.MedianfilterSpinner.Value;
                    if value > 1
                        app.realImage = medfilt2(app.realImage, [value, value]);
                    end
                    %
                    app.realImage = app.realImage + img;
                    app.update_realImage();
                    app.CurrentimageEditField.Value = ii;                    
                end;
            end;
           
            app.sumImage = app.realImage;            
            app.update_realImage();
            app.update_difference();
        end

        function autoAxis(~, axis)
            axis.CLim(2) = inf;
            axis.CLim(1) = -inf;
        end
        
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %____Instructions_____
            %connect to micromanager
            %first install PV cam including PV cam test, see if its finds
            %the camera, then install micro manager and see if it finds the
            %camera, then follow instructions on 
            % https://micro-manager.org/Matlab_Configuration to connect to matalb
            %to micromanager, when trying to change classpath and
            %librarypath.txt you will encounter an acess denied error,
            %close matlab, open programme files/MATLAB/R2023a (your matlab
            %version)/toolbox/local and replace the files manually with the
            %modified ones. 
            import mmcorej.*; 
            app.mmc = CMMCore; 
            app.mmc.unloadAllDevices();
            app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0\MMConfig_pvcam_simple.cfg');
            app.ErrorlaberLabel.Text = 'cam initialized';
            app.mmc.setExposure(app.ExposuretimeSpinner.Value);

            % get allowed binning
            binnings = app.mmc.getAllowedPropertyValues("Camera", "Binning");
            for s = 1:binnings.size()
                items{s} = char(binnings.get(s-1));
%                 app.BinningDropDown.Items{s} = string(binnings.get(s));
%                 app.BinningDropDown.ItemsData{s} = string(binnings.get(s));
            end
            app.BinningDropDown.Items = items;
            app.BinningDropDown.ItemsData = items;

            % get allowed PModes
            PModes = app.mmc.getAllowedPropertyValues("Camera", "PMode");
            for s = 1:PModes.size()
                modes{s} = char(PModes.get(s-1));
            end
            app.PModeDropDown.Items = modes;
            app.PModeDropDown.ItemsData = modes;

            % get allowed Gains
            Gains = app.mmc.getAllowedPropertyValues("Camera", "Gain");
            for s = 1:Gains.size()
                gains{s} = char(Gains.get(s-1));
            end
            app.GainDropDown.Items = gains;
            app.GainDropDown.ItemsData = gains;

             % get allowed ReadoutRates
            Rates = app.mmc.getAllowedPropertyValues("Camera", "ReadoutRate");
            for s = 1:Rates.size()
                rates{s} = char(Rates.get(s-1));
            end
            app.ReadoutRateDropDown.Items = rates;
            app.ReadoutRateDropDown.ItemsData = rates;
            %
            app.Axes_realImage.Colormap = gray;
            app.Axes_difference.Colormap = gray;
            app.takeSnap;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app.mmc.unloadAllDevices();
            end
            delete(app.UIFigure) 
        end

        % Button pushed function: StartliveButton
        function StartliveButtonPushed(app, event)
            app.isStop = false;
            while ~app.isStop
                app.takeSnap;
                pause(app.PausebetweenframesmsIncreaseitifappdoesnotresponseSpinner.Value / 1000);
            end
        end

        % Button pushed function: MakereferenceButton
        function MakereferenceButtonPushed(app, event)
            app.referenceImage = app.realImage;
            app.update_difference();
        end

        % Value changed function: ExposuretimeSpinner
        function ExposuretimeSpinnerValueChanged(app, event)
            app.mmc.setExposure(app.ExposuretimeSpinner.Value);
        end

        % Value changed function: CminEditField
        function CminEditFieldValueChanged(app, event)
            value = app.CminEditField.Value;
            if value < app.Axes_realImage.CLim(2)
                app.Axes_realImage.CLim(1) = value;
            else
                app.CminEditField.Value = app.Axes_realImage.CLim(2)-1;
                app.Axes_realImage.CLim(1) = app.CminEditField.Value;
            end            
        end

        % Value changed function: CmaxEditField
        function CmaxEditFieldValueChanged(app, event)
            value = app.CmaxEditField.Value;
            if value > app.Axes_realImage.CLim(1)
                app.Axes_realImage.CLim(2) = value;
            else
                app.CmaxEditField.Value = app.Axes_realImage.CLim(2)+1;
                app.Axes_realImage.CLim(2) = app.CmaxEditField.Value;
            end
        end

        % Value changed function: CminEditField_2
        function CminEditField_2ValueChanged(app, event)
            value = app.CminEditField_2.Value;
            if value < app.Axes_difference.CLim(2)
                app.Axes_difference.CLim(1) = value;
            else
                app.CminEditField_2.Value = app.Axes_difference.CLim(2)-1;
                app.Axes_difference.CLim(1) = app.CminEditField_2.Value;
            end
        end

        % Value changed function: CmaxEditField_2
        function CmaxEditField_2ValueChanged(app, event)
            value = app.CmaxEditField_2.Value;
            if value > app.Axes_difference.CLim(1)
                app.Axes_difference.CLim(2) = value;
            else
                app.CmaxEditField_2.Value = app.Axes_difference.CLim(1)+1;
                app.Axes_difference.CLim(2) = app.CmaxEditField_2.Value;
            end
        end

        % Button pushed function: StopliveButton
        function StopliveButtonPushed(app, event)
            app.isStop = true;
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            if strcmp(value, 'parula')
                c = parula;
            elseif strcmp(value, 'turbo')
                c = turbo;
            else
                c = gray;
            end;
            app.Axes_realImage.Colormap = c;
        end

        % Value changed function: ColormapDropDown_2
        function ColormapDropDown_2ValueChanged(app, event)
            value = app.ColormapDropDown_2.Value;
            if strcmp(value, 'parula')
                c = parula;
            elseif strcmp(value, 'turbo')
                c = turbo;
            else
                c = gray;
            end;
            app.Axes_difference.Colormap = c;
        end

        % Button pushed function: SnapButton
        function SnapButtonPushed(app, event)
            app.takeSnap;
        end

        % Button pushed function: SelectFolderButton_2
        function SelectFolderButton_2Pushed(app, event)
            % Display uigetfile dialog
            path = uigetdir(app.pathEditField.Value);
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(path))
                app.pathEditField.Value = path;
            end
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            %disable save button during saving
            app.SaveButton_2.Enable = 'off';
            value = true;
            if isempty(app.pathEditField.Value)
                app.ErrorlaberLabel.Text = 'Empty path!!!!';
                app.ErrorsLamp.Color = 'r';
                value = false;
            end
            if value
                try
                    folderPath = app.pathEditField.Value; %use path specifield in pathEditField
                    fileName = app.filenameEditField.Value;
                    % Save the imageArray
                    fullName = fullfile(folderPath, fileName);
                    %check is file exist    
                    if exist(fullName, 'file') ~= 0
                        if strcmp(questdlg('File already exists. Overwrite?'),'Yes')
                            if not(app.SaveonlydifferenceCheckBox.Value)
                                writematrix(app.sumImage, fullName)
                                exportgraphics(app.Axes_realImage,[fullName(1:end-4), '.png']);
                                % Save difference image array
                                if app.SavedifferenceCheckBox.Value
                                    fullName = fullfile(folderPath, [fileName(1:end-4), '_diff.dat']);
                                    writematrix(int16(app.sumImage) - int16(app.referenceImage), fullName)
                                    exportgraphics(app.Axes_difference,[fullName(1:end-4), '.png']);
                                end
                            else
                                fullName = fullfile(folderPath, [fileName(1:end-4), '_diff.dat']);
                                writematrix(int16(app.sumImage) - int16(app.referenceImage), fullName)
                                exportgraphics(app.Axes_difference,[fullName(1:end-4), '.png']);
                            end
                        end
                    else
                        if not(app.SaveonlydifferenceCheckBox.Value)
                            writematrix(app.sumImage, fullName)
                            exportgraphics(app.Axes_realImage,[fullName(1:end-4), '.png']);
                            % Save difference image array
                            if app.SavedifferenceCheckBox.Value
                                fullName = fullfile(folderPath, [fileName(1:end-4), '_diff.dat']);
                                writematrix(int16(app.sumImage) - int16(app.referenceImage), fullName)
                                exportgraphics(app.Axes_difference,[fullName(1:end-4), '.png']);
                            end
                        else
                            fullName = fullfile(folderPath, [fileName(1:end-4), '_diff.dat']);
                            writematrix(int16(app.sumImage) - int16(app.referenceImage), fullName)
                            exportgraphics(app.Axes_difference,[fullName(1:end-4), '.png']);
                        end
                    end                    
                    app.ErrorlaberLabel.Text = 'images saved sucessfully';
                    app.ErrorsLamp.Color = 'g';
                catch exception
                    app.ErrorsLamp.Color = 'r';
                    % Handle the error
                    if strcmp(exception.identifier, 'MATLAB:FileIO:InvalidFid')
                        app.ErrorlaberLabel.Text = 'invalid path';
                    end
                end
            end
            %enable save button
            app.SaveButton_2.Enable = 'on';
        end

        % Button pushed function: AutoClimButton
        function AutoClimButtonPushed(app, event)
            autoAxis(app, app.Axes_realImage);
        end

        % Button pushed function: AutoClimButton_2
        function AutoClimButton_2Pushed(app, event)
            autoAxis(app, app.Axes_difference);
        end

        % Value changed function: AutoCheckBox
        function AutoCheckBoxValueChanged(app, event)
            if app.AutoCheckBox.Value
                autoAxis(app, app.Axes_realImage);
            else
                app.CminEditField.ValueChangedFcn(app, event);
                app.CmaxEditField.ValueChangedFcn(app, event);
            end
        end

        % Value changed function: BinningDropDown
        function BinningDropDownValueChanged(app, event)
            value = app.BinningDropDown.Value;
            app.mmc.setProperty('Camera','Binning',value);
%             if strcmp(value, '1x1')
%                 app.mmc.setProperty('Camera','Binning','1x1');
%             elseif strcmp(value, '2x2')
%                 app.mmc.setProperty('Camera','Binning','2x2');
%             elseif strcmp(value, '4x4')
%                 app.mmc.setProperty('Camera','Binning','4x4');
%             end
        end

        % Value changed function: PModeDropDown
        function PModeDropDownValueChanged(app, event)
            value = app.PModeDropDown.Value;
%             app.mmc.setProperty('Camera','PMode',value);
              %does not work
%             with Retiga R3
        end

        % Value changed function: GainDropDown
        function GainDropDownValueChanged(app, event)
            value = app.GainDropDown.Value;
            app.mmc.setProperty('Camera','Gain',value);
        end

        % Value changed function: ReadoutRateDropDown
        function ReadoutRateDropDownValueChanged(app, event)
            value = app.ReadoutRateDropDown.Value;
            app.mmc.setProperty('Camera','ReadoutRate',value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Axes_difference = uiaxes(app.UIFigure);
            title(app.Axes_difference, 'Difference')
            zlabel(app.Axes_difference, 'Z')
            app.Axes_realImage = uiaxes(app.UIFigure);
            title(app.Axes_realImage, 'image')
            zlabel(app.Axes_realImage, 'Z')
            app.AxisHysto_real = uiaxes(app.UIFigure);
            title(app.AxisHysto_real, 'Hystogram')
            xlabel(app.AxisHysto_real, 'counts')
            ylabel(app.AxisHysto_real, 'N')
            zlabel(app.AxisHysto_real, 'Z')
            app.AxisHysto_diff = uiaxes(app.UIFigure);
            title(app.AxisHysto_diff, 'Hystogram')
            xlabel(app.AxisHysto_diff, 'counts')
            ylabel(app.AxisHysto_diff, 'N')
            zlabel(app.AxisHysto_diff, 'Z')
            app.StartliveButton = uibutton(app.UIFigure, 'push');
            app.StartliveButton.ButtonPushedFcn = createCallbackFcn(app, @StartliveButtonPushed, true);
            app.StartliveButton.Text = 'Start live';
            app.StopliveButton = uibutton(app.UIFigure, 'push');
            app.StopliveButton.ButtonPushedFcn = createCallbackFcn(app, @StopliveButtonPushed, true);
            app.StopliveButton.Text = 'Stop live';
            app.MakereferenceButton = uibutton(app.UIFigure, 'push');
            app.MakereferenceButton.ButtonPushedFcn = createCallbackFcn(app, @MakereferenceButtonPushed, true);
            app.MakereferenceButton.Text = 'Make reference';
            app.ExposuretimeSpinnerLabel = uilabel(app.UIFigure);
            app.ExposuretimeSpinnerLabel.Text = 'Exposure time';
            app.ExposuretimeSpinner = uispinner(app.UIFigure);
            app.ExposuretimeSpinner.Limits = [1 600000];
            app.ExposuretimeSpinner.ValueChangedFcn = createCallbackFcn(app, @ExposuretimeSpinnerValueChanged, true);
            app.ExposuretimeSpinner.Value = 100;
            app.CminEditFieldLabel = uilabel(app.UIFigure);
            app.CminEditFieldLabel.Text = 'Cmin';
            app.CminEditField = uieditfield(app.UIFigure, 'numeric');
            app.CminEditField.ValueChangedFcn = createCallbackFcn(app, @CminEditFieldValueChanged, true);
            app.CmaxEditFieldLabel = uilabel(app.UIFigure);
            app.CmaxEditFieldLabel.Text = 'Cmax';
            app.CmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.CmaxEditField.ValueChangedFcn = createCallbackFcn(app, @CmaxEditFieldValueChanged, true);
            app.pathEditFieldLabel = uilabel(app.UIFigure);
            app.pathEditFieldLabel.Text = 'path';
            app.pathEditField = uieditfield(app.UIFigure, 'text');
            app.pathEditField.Value = 'f:\Data\TESTS\Images from MM\';
            app.filenameEditFieldLabel = uilabel(app.UIFigure);
            app.filenameEditFieldLabel.Text = 'file name';
            app.filenameEditField = uieditfield(app.UIFigure, 'text');
            app.filenameEditField.Value = 'test1.dat';
            app.CmaxEditField_2Label = uilabel(app.UIFigure);
            app.CmaxEditField_2Label.Text = 'Cmax';
            app.CmaxEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CmaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @CmaxEditField_2ValueChanged, true);
            app.CminEditField_2Label = uilabel(app.UIFigure);
            app.CminEditField_2Label.Text = 'Cmin';
            app.CminEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CminEditField_2.ValueChangedFcn = createCallbackFcn(app, @CminEditField_2ValueChanged, true);
            app.AppSettingPanel = uipanel(app.UIFigure);
            app.AppSettingPanel.Title = 'App Setting';
            app.PausebetweenframesmsIncreaseitifappdoesnotresponseLabel = uilabel(app.AppSettingPanel);
            app.PausebetweenframesmsIncreaseitifappdoesnotresponseLabel.Text = {'Pause between frames (ms).'; 'Increase it if app does not response'};
            app.PausebetweenframesmsIncreaseitifappdoesnotresponseSpinner = uispinner(app.AppSettingPanel);
            app.PausebetweenframesmsIncreaseitifappdoesnotresponseSpinner.Limits = [100 8000];
            app.PausebetweenframesmsIncreaseitifappdoesnotresponseSpinner.Value = 500;
            app.ErrorlaberLabel = uilabel(app.AppSettingPanel);
            app.ErrorlaberLabel.Text = 'Error laber';
            app.ErrorsLampLabel = uilabel(app.AppSettingPanel);
            app.ErrorsLampLabel.Text = 'Errors';
            app.ErrorsLamp = uilamp(app.AppSettingPanel);
            app.NofimgstostackSpinnerLabel = uilabel(app.UIFigure);
            app.NofimgstostackSpinnerLabel.Text = 'N of imgs to stack';
            app.NofimgstostackSpinner = uispinner(app.UIFigure);
            app.NofimgstostackSpinner.Limits = [0 Inf];
            app.NofimgstostackSpinner.ValueDisplayFormat = '%.0f';
            app.NofimgstostackSpinner.Value = 1;
            app.CurrentimageEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentimageEditFieldLabel.Text = 'Current image';
            app.CurrentimageEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentimageEditField.ValueDisplayFormat = '%.0f';
            app.CurrentimageEditField.Editable = 'off';
            app.ColormapDropDownLabel = uilabel(app.UIFigure);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.UIFigure);
            app.ColormapDropDown.Items = {'gray', 'parula', 'turbo'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'gray';
            app.ColormapDropDown_2Label = uilabel(app.UIFigure);
            app.ColormapDropDown_2Label.Text = 'Colormap';
            app.ColormapDropDown_2 = uidropdown(app.UIFigure);
            app.ColormapDropDown_2.Items = {'gray', 'parula', 'turbo'};
            app.ColormapDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDown_2ValueChanged, true);
            app.ColormapDropDown_2.Value = 'gray';
            app.SnapButton = uibutton(app.UIFigure, 'push');
            app.SnapButton.ButtonPushedFcn = createCallbackFcn(app, @SnapButtonPushed, true);
            app.SnapButton.Text = 'Snap';
            app.SelectFolderButton_2 = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButton_2Pushed, true);
            app.SelectFolderButton_2.Text = 'Select Folder';
            app.SaveButton_2 = uibutton(app.UIFigure, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.AutoClimButton = uibutton(app.UIFigure, 'push');
            app.AutoClimButton.ButtonPushedFcn = createCallbackFcn(app, @AutoClimButtonPushed, true);
            app.AutoClimButton.Text = 'Auto Clim';
            app.AutoClimButton_2 = uibutton(app.UIFigure, 'push');
            app.AutoClimButton_2.ButtonPushedFcn = createCallbackFcn(app, @AutoClimButton_2Pushed, true);
            app.AutoClimButton_2.Text = 'Auto Clim';
            app.AutoCheckBox = uicheckbox(app.UIFigure);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBoxValueChanged, true);
            app.AutoCheckBox.Text = 'Auto';
            app.AutoCheckBox_2 = uicheckbox(app.UIFigure);
            app.AutoCheckBox_2.Text = 'Auto';
            app.BinningDropDownLabel = uilabel(app.UIFigure);
            app.BinningDropDownLabel.Text = 'Binning';
            app.BinningDropDown = uidropdown(app.UIFigure);
            app.BinningDropDown.Items = {'1x1', '2x2', '4x4'};
            app.BinningDropDown.ValueChangedFcn = createCallbackFcn(app, @BinningDropDownValueChanged, true);
            app.BinningDropDown.Value = '1x1';
            app.MedianfilterSpinnerLabel = uilabel(app.UIFigure);
            app.MedianfilterSpinnerLabel.Text = 'Median filter';
            app.MedianfilterSpinner = uispinner(app.UIFigure);
            app.MedianfilterSpinner.Limits = [1 5];
            app.MedianfilterSpinner.ValueDisplayFormat = '%.0f';
            app.MedianfilterSpinner.Value = 1;
            app.MeanIntEditFieldLabel = uilabel(app.UIFigure);
            app.MeanIntEditFieldLabel.Text = 'Mean Int';
            app.MeanIntEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeanIntEditField.Editable = 'off';
            app.MeanIntEditField_2Label = uilabel(app.UIFigure);
            app.MeanIntEditField_2Label.Text = 'Mean Int';
            app.MeanIntEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.MeanIntEditField_2.Editable = 'off';
            app.SavedifferenceCheckBox = uicheckbox(app.UIFigure);
            app.SavedifferenceCheckBox.Text = 'Save difference?';
            app.SaveonlydifferenceCheckBox = uicheckbox(app.UIFigure);
            app.SaveonlydifferenceCheckBox.Text = 'Save only difference?';
            app.PModeDropDownLabel = uilabel(app.UIFigure);
            app.PModeDropDownLabel.Text = 'PMode';
            app.PModeDropDown = uidropdown(app.UIFigure);
            app.PModeDropDown.Items = {'1x1', '2x2', '4x4'};
            app.PModeDropDown.ValueChangedFcn = createCallbackFcn(app, @PModeDropDownValueChanged, true);
            app.PModeDropDown.Value = '1x1';
            app.GainDropDownLabel = uilabel(app.UIFigure);
            app.GainDropDownLabel.Text = 'Gain';
            app.GainDropDown = uidropdown(app.UIFigure);
            app.GainDropDown.Items = {'1x1', '2x2', '4x4'};
            app.GainDropDown.ValueChangedFcn = createCallbackFcn(app, @GainDropDownValueChanged, true);
            app.GainDropDown.Value = '1x1';
            app.ReadoutRateDropDownLabel = uilabel(app.UIFigure);
            app.ReadoutRateDropDownLabel.Text = 'ReadoutRate';
            app.ReadoutRateDropDown = uidropdown(app.UIFigure);
            app.ReadoutRateDropDown.Items = {'1x1', '2x2', '4x4'};
            app.ReadoutRateDropDown.ValueChangedFcn = createCallbackFcn(app, @ReadoutRateDropDownValueChanged, true);
            app.ReadoutRateDropDown.Value = '1x1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cam_imaging_live

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end